classdef FIRFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        outputTimeFig      matlab.ui.control.UIAxes
        outputSpectrumFig  matlab.ui.control.UIAxes
        Button             matlab.ui.control.Button
    end


    properties (Access = public)
        callingApp % Description
        
        outputSig
        
    end

    properties (Access = private)
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.callingApp = main;
            
            app.outputSig = conv(main.testSig, main.filterSig);
                        % 更新数据
            N = length(app.outputSig); % 原信号点数
            n  = linspace(0,N-1,N);
            fft_y = fft(app.outputSig); % 计算fft
            
            % n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 角频率(未归一化)
            plot(app.outputTimeFig, app.outputSig);
            plot(app.outputSpectrumFig, w/pi,(abs(fft_y)));
            % plot(app.outputPhaseFig, w/pi,angle(fft_y));
            
            main.outputSig = app.outputSig;
            
        end

        % Button pushed function: Button
        function playOutputSig(app, event)
            sound(app.outputSig,44100);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.outputTimeFig = uiaxes(app.UIFigure);
            title(app.outputTimeFig, '时域')
            xlabel(app.outputTimeFig, 't')
            ylabel(app.outputTimeFig, '信号大小')
            app.outputSpectrumFig = uiaxes(app.UIFigure);
            title(app.outputSpectrumFig, '幅度响应')
            xlabel(app.outputSpectrumFig, '归一化角频率 ω (x\times \pi rads/sample)')
            ylabel(app.outputSpectrumFig, '幅频衰减(dB)')
            app.outputSpectrumFig.XLim = [0 1];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @playOutputSig, true);
            app.Button.Text = '播放';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FIRFilter(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end