classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        readAudioFilebtn      matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        UIAxes_2              matlab.ui.control.UIAxes
        Button_4              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_6              matlab.ui.control.StateButton
        Label_2               matlab.ui.control.Label
        UIAxes_3              matlab.ui.control.UIAxes
        Button_7              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_9              matlab.ui.control.StateButton
        Label_3               matlab.ui.control.Label
        recordBtn             matlab.ui.control.StateButton
        originSIgSpectrum     matlab.ui.control.UIAxes
        originPlayBtn         matlab.ui.control.Button
        SineSigGeneratePanel  matlab.ui.container.Panel
        Label_4               matlab.ui.control.Label
        sineSampleFreq        matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        EditField_2           matlab.ui.control.NumericEditField
        sin2piftLabel         matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        sineNum               matlab.ui.control.Spinner
        sineGenFig            matlab.ui.control.UIAxes
        sineConfirm           matlab.ui.control.Button
        singQuit              matlab.ui.control.Button
        Label_6               matlab.ui.control.Label
        EditField_3           matlab.ui.control.NumericEditField
        Spinner               matlab.ui.control.Spinner
        HzLabel               matlab.ui.control.Label
        HzLabel_2             matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        Label_8               matlab.ui.control.Label
    end


    properties (Access = private)
        audioRecorderObj  % 音频录制对象, 用来录制,保存音频数据
    end

    properties (Access = public)
        originSig % 原信号 vector : 用来保存音频数据 
    end

    methods (Access = private)
    
        function results = updateOrigin(app)
            % 更新原信号模块
            
            
            % 绘制原信号音频--时域图
            plot(app.UIAxes,app.originSig);
            
            % 绘制原信号音频--频谱图
            N = length(app.originSig); % 原信号点数
            fft_y = fft(app.originSig); % fft


            n = linspace(0 , N-1,N); % 第n点
            w = 2*pi*n./N; % 归一化后的角频率
            
            plot(app.originSIgSpectrum,w/pi,abs(fft_y)); % 绘制频谱图
            
        end
        
        function results = redrawPanel(app)
            % 重新绘制Panel数据
            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % 隐藏正弦函数叠加模块
            set(app.SineSigGeneratePanel,'visible','off');
            % 初始化音频录制对象
            app.audioRecorderObj = audiorecorder; 
            
            
        end

        % Button pushed function: readAudioFilebtn
        function readAudioFilebtnPushed(app, event)
            
            % 打开文件选择框
            [file,path] = uigetfile({'*.wav';'*.ogg';'*.flac';'*.au';'*.aiff';'*.aif';'*.aifc';'*.mp3';'*.m4a';'*.mp4';'*.*'},...
                          'Surpported Audio File');;  
            
            % 如果选择了文件那么打开音频文件
            if path ~= 0
                % 获得文件地址
                audio_file_path = [path,file]; 
                % 打开音频文件
                [app.originSig,Fs] = audioread(audio_file_path);
                updateOrigin(app); % 更新
            end
        end

        % Value changed function: recordBtn
        function recordBtnValueChanged(app, event)
            value = app.recordBtn.Value;
           
            % 按下录制按钮
            if value== 1
                record(app.audioRecorderObj); % 开始录制
            % 结束录制
            else
                app.originSig = getaudiodata(app.audioRecorderObj); % 获取录制数据
                stop(app.audioRecorderObj); % 停止录制
                updateOrigin(app); % 更新
            end


        end

        % Button pushed function: originPlayBtn
        function originPlayBtnPushed(app, event)
            % 播放原信号(生成的信号)
            sound(app.originSig) 
        end

        % Button pushed function: Button_3
        function FreeGenerateSignal(app, event)
            set(app.SineSigGeneratePanel,'visible','on'); % 打开正弦函数控制面板
            
            f = 100 ; % Hz
            t = linspace(0, 1/f);
            
            sineNum = app.sineNum.Value; % sine 正弦函数的个数
            value = app.sineSampleFreq.Value; % 获取控件的值
            
            sample_f = 200;  % Hz
            N = 50; % 采样点数
            n = linspace(0 , N-1, N);
            sample_t = n / sample_f % 采样时间位置
           
            plot(app.sineGenFig, t, 100*sin(2*pi*f*t)); % 绘制模拟信号图
            
            hold( app.sineGenFig, 'on' ); % 保持图形可用 
            
            stem(app.sineGenFig, sample_t, 100*sin(2*pi*f*sample_t)); % 绘制采样后的图
            
            
        end

        % Callback function
        function closePanelBtnButtonPushed(app, event)
            set(app.SineSigGeneratePanel,'visible','off');
        end

        % Button pushed function: singQuit
        function singQuitButtonPushed(app, event)
            % 退出控制面板
            set(app.SineSigGeneratePanel,'visible','off');
        end

        % Button pushed function: sineConfirm
        function sineConfirmButtonPushed(app, event)
            set(app.SineSigGeneratePanel,'visible','on');
        end

        % Value changed function: sineSampleFreq
        function sineFreqChange(app, event)
            value = app.sineSampleFreq.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '时域图')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, '信号幅值')
            app.readAudioFilebtn = uibutton(app.UIFigure, 'push');
            app.readAudioFilebtn.ButtonPushedFcn = createCallbackFcn(app, @readAudioFilebtnPushed, true);
            app.readAudioFilebtn.Text = '从文件中读取';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @FreeGenerateSignal, true);
            app.Button_3.Text = '自由生成';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '信号生成';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '播放';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '信号接收';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '从文件中读取';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '自由生成';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.Text = '语音输入';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '噪音生成';
            app.recordBtn = uibutton(app.UIFigure, 'state');
            app.recordBtn.ValueChangedFcn = createCallbackFcn(app, @recordBtnValueChanged, true);
            app.recordBtn.Text = '语音输入';
            app.originSIgSpectrum = uiaxes(app.UIFigure);
            title(app.originSIgSpectrum, '频谱图')
            xlabel(app.originSIgSpectrum, 'w/\pi')
            ylabel(app.originSIgSpectrum, '信号幅值')
            app.originPlayBtn = uibutton(app.UIFigure, 'push');
            app.originPlayBtn.ButtonPushedFcn = createCallbackFcn(app, @originPlayBtnPushed, true);
            app.originPlayBtn.Text = '播放';
            app.SineSigGeneratePanel = uipanel(app.UIFigure);
            app.SineSigGeneratePanel.Title = '正弦函数生成面板';
            app.Label_4 = uilabel(app.SineSigGeneratePanel);
            app.Label_4.Text = '采样频率';
            app.sineSampleFreq = uieditfield(app.SineSigGeneratePanel, 'numeric');
            app.sineSampleFreq.Limits = [1 Inf];
            app.sineSampleFreq.ValueDisplayFormat = '%.1f';
            app.sineSampleFreq.ValueChangedFcn = createCallbackFcn(app, @sineFreqChange, true);
            app.sineSampleFreq.Value = 44100;
            app.EditField_2Label = uilabel(app.SineSigGeneratePanel);
            app.EditField_2Label.Text = '采样点数';
            app.EditField_2 = uieditfield(app.SineSigGeneratePanel, 'numeric');
            app.sin2piftLabel = uilabel(app.SineSigGeneratePanel);
            app.sin2piftLabel.Text = '100 sin(2\pi ft)';
            app.Label_5 = uilabel(app.SineSigGeneratePanel);
            app.Label_5.Text = '正弦信号数量';
            app.sineNum = uispinner(app.SineSigGeneratePanel);
            app.sineNum.Limits = [1 Inf];
            app.sineNum.Value = 1;
            app.sineGenFig = uiaxes(app.SineSigGeneratePanel);
            title(app.sineGenFig, 'Title')
            xlabel(app.sineGenFig, 'X')
            ylabel(app.sineGenFig, 'Y')
            app.sineConfirm = uibutton(app.SineSigGeneratePanel, 'push');
            app.sineConfirm.ButtonPushedFcn = createCallbackFcn(app, @sineConfirmButtonPushed, true);
            app.sineConfirm.Text = '确认';
            app.singQuit = uibutton(app.SineSigGeneratePanel, 'push');
            app.singQuit.ButtonPushedFcn = createCallbackFcn(app, @singQuitButtonPushed, true);
            app.singQuit.Text = '退出';
            app.Label_6 = uilabel(app.SineSigGeneratePanel);
            app.Label_6.Text = '第                   个正弦信号频率设定';
            app.EditField_3 = uieditfield(app.SineSigGeneratePanel, 'numeric');
            app.EditField_3.Value = 100;
            app.Spinner = uispinner(app.SineSigGeneratePanel);
            app.Spinner.Value = 1;
            app.HzLabel = uilabel(app.SineSigGeneratePanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.SineSigGeneratePanel);
            app.HzLabel_2.Text = 'Hz';
            app.Label_7 = uilabel(app.SineSigGeneratePanel);
            app.Label_7.Text = '点';
            app.Label_8 = uilabel(app.SineSigGeneratePanel);
            app.Label_8.Text = '个';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end