classdef noiseGenApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_6         matlab.ui.control.Label
        sineConfirm     matlab.ui.control.Button
        singQuit        matlab.ui.control.Button
        sinePosSpinner  matlab.ui.control.Spinner
        HzLabel         matlab.ui.control.Label
        HzLabel_2       matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        sineSampleFreq  matlab.ui.control.NumericEditField
        Label_8         matlab.ui.control.Label
        sampleNum       matlab.ui.control.NumericEditField
        Label_4         matlab.ui.control.Label
        sineNum         matlab.ui.control.Spinner
        Label_5         matlab.ui.control.Label
        sinePosFreq     matlab.ui.control.NumericEditField
        sineGenFig      matlab.ui.control.UIAxes
        Button          matlab.ui.control.Button
        Label_7         matlab.ui.control.Label
        ListBox         matlab.ui.control.ListBox
    end


    properties (Access = public)
        % 调用该窗口的窗口实例
        callingApp  
        % sine信号
        sineSig = 0 
        
        % 正弦信号个数
        sn 
        
        % 频率列表 1..sn
        f 
        
        % 采样点数
        N
        
        % 指定的正弦函数
        sinFunc = @(f, t) 100*sin(2*pi*f*t); %正弦函数
        
        % 采样频率
        fs
        
        % 默认频率 1kHz
        SINE_FREQ = 1000 
 
    end

    methods (Access = public)
    
        function [] = updateCurrentFreq(app)
            % 获取当前设置的位置
            pos = app.sinePosSpinner.Value;
            
            % 设置当前设置的频率值
            app.f(pos) = app.sinePosFreq.Value;
        end
        
        function [] = draw(app)
        % ====根据属性数据绘制图形====
               
            % 初始化正弦信号值 
            app.sineSig=zeros(app.N,1); % 初始化正弦数据列表
            
            % 计算采样时间位置 
            np = app.N; % 采样点数
            n = linspace(0 , np-1,np);  % vector 采样向量 [0..N-1]
            sample_t = n' / app.fs; %  Nx1 vector 采样时间位置 = 采样点/采样频率
            
            % 遍历采样共sn个正弦信号
            for i=1:app.sn
                sin_i = app.sinFunc(app.f(i) , sample_t); % Nx1 f(i)为第i个的频率, sample_t为采样时间点 
                app.sineSig =app.sineSig +sin_i;   % Nx1
            end
            
            %  绘制采样后的图
             stem(app.sineGenFig, sample_t, app.sineSig); % 绘制采样后的图
        end
        
        function [] = setAndDraw(app)
        % 设置信号属性并且将信号其绘制
               
            % 初始化正弦信号值 
            app.sineSig=zeros(app.N,1); % 初始化正弦数据列表
            
            % 计算采样时间位置 
            np = app.N; % 采样点数
            n = linspace(0 , np-1,np);  % vector 采样向量 [0..N-1]
            sample_t = n' ./ app.fs; %  Nx1 vector 采样时间位置 = 采样点/采样频率
            
            % 遍历采样共sn个正弦信号
            for i=1:app.sn
                sin_i = app.sinFunc(app.f(i) , sample_t); % Nx1 f(i)为第i个的频率, sample_t为采样时间点 
                app.sineSig =app.sineSig +sin_i;   % Nx1
            end
            
            %  绘制采样后的图
             stem(app.sineGenFig, sample_t, app.sineSig); % 绘制采样后的图
        end
        
    end

    methods (Access = private)
    
        function [] = reset(app, which)
            % 控制面板重置
            
            if(nargin < 2)
            % 未指定重置哪块内容,全部重置
                
            
            else
            % 重置指定模块
                
                if(strcmp(which, 'single'))
                % 重置单频控制模块
                    app.sinePosFreq.Value = app.SINE_FREQ;
                    app.sinePosSpinner.Value = 1; % 默认第一个是 默认频率   
                end
                
            end
            
        end
        
        function [] = setSignal(app)
        % 根据现有数据来建立信号, 生成sineSig属性
        
            % 初始化正弦信号值 
            app.sineSig=zeros(app.N,1); % 初始化正弦数据列表
            
            % 计算采样时间位置 
            np = app.N; % 采样点数
            n = linspace(0 , np-1,np);  % vector 采样向量 [0..N-1]
            sample_t = n' / app.fs; %  Nx1 vector 采样时间位置 = 采样点/采样频率
            
            % 遍历生成正弦信号
            for i=1:app.sn
                sin_i = app.sinFunc(app.f(i) , sample_t); % Nx1 f(i)为第i个的频率, sample_t为采样时间点 
                app.sineSig =app.sineSig +sin_i;   % Nx1
            end
        
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function starupFcn(app, mainapp)
            
            if nargin<=1
                return 
            end
            
            app.callingApp = mainapp; % 保存调用该窗口的窗口对象
            
            % 采样点数无法编辑


            app.sn = app.sineNum.Value; % 正弦信号个数
            app.fs= app.sineSampleFreq.Value; % 更新采样频率
            app.N= app.sampleNum.Value; % 更新采样点数
            app.f = ones(app.sn,1) .* app.SINE_FREQ; % 初始化频率值列表
            
            % 初始绘制一个图
            setAndDraw(app);    
           

        end

        % Callback function
        function openApp(app, event)


        end

        % Button pushed function: sineConfirm
        function sineConfirmCallback(app, event)
            
            % 应用
            sineApply(app, event);        
            
            % 退出
            delete(app);
            
        end

        % Value changed function: sineNum
        function sineNumChangeCallback(app, event)
        % 更新正弦信号的个数
        
            % 重置单一正弦信号控制块
            reset(app, 'single');
        
            % 正弦信号数量
            app.sn = app.sineNum.Value;  % scalar 
            
            % 重置频率列表 (利用默认频率)
            app.f = ones(app.sn,1) .* app.SINE_FREQ;
            
            % 绘制图形
            setAndDraw(app);
        end

        % Value changed function: sineSampleFreq
        function freqUpdateCallback(app, event)
            app.fs= app.sineSampleFreq.Value; % 更新采样频率
            
            % 重新绘制图形
            setAndDraw(app);
        end

        % Value changed function: sampleNum
        function sampleNumValueChanged(app, event)
            
            % 更新采样点数
            app.N= app.sampleNum.Value;
            
            % 重新绘制
            setAndDraw(app);
        end

        % Value changed function: sinePosFreq
        function posFreqChange(app, event)
            
            % 更新当前指定位置正弦信号的频率
            updateCurrentFreq(app);
            
            % 更新信号且绘制
            setAndDraw(app);
        end

        % Callback function: sinePosSpinner
        function posChange(app, event)
            
            % 获取更新位置
            pos = app.sinePosSpinner.Value;
            
            if(pos <= app.sineNum.Value )
            % 如果未越界则更新
                app.sinePosFreq.Value = app.f(pos);
            else
            % 越界了就固定为最高值
                app.sinePosSpinner.Value = app.sineNum.Value + 0.0;
            end
        end

        % Button pushed function: Button
        function sineApply(app, event)
            
            % 更新数据
            app.callingApp.originSig = app.sineSig;
            
            % 绘制图像
            app.callingApp.updateOrigin();
        end

        % Button pushed function: singQuit
        function quit(app, event)
            delete(app);
        end

        % Value changed function: ListBox
        function noiseGenSelect(app, event)
            value = app.ListBox.Value;
            value
            if strcmp(value, '正弦信号')
               
               
               %
            elseif strcmp(value,'白噪音' )
               % 白噪声
               set(app.sineNum,'visible','off');
               set(app.sineSampleFreq,'visible','off');
               set(app.sampleNum,'visible', 'off');
               set(app.sinePosFreq,'visible', 'off');
               set(app.sinePosSpinner,'visible', 'off');
     
               app.Label.Visible= 'off';
               app.HzLabel_2.Visible='off';
               app.HzLabel.Visible='off';
               app.Label_2.Visible='off';
               app.Label_3.Visible='off';
               app.Label_8.Visible='off';
               app.Label_4.Visible = 'off';
               app.Label_5.Visible='off';
                
            end
            
            
            % 设置可见
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '正弦信号生成系统';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '噪音生成模块';
            app.sineConfirm = uibutton(app.UIFigure, 'push');
            app.sineConfirm.ButtonPushedFcn = createCallbackFcn(app, @sineConfirmCallback, true);
            app.sineConfirm.Text = '确认';
            app.singQuit = uibutton(app.UIFigure, 'push');
            app.singQuit.ButtonPushedFcn = createCallbackFcn(app, @quit, true);
            app.singQuit.Text = '退出';
            app.sinePosSpinner = uispinner(app.UIFigure);
            app.sinePosSpinner.ValueChangingFcn = createCallbackFcn(app, @posChange, true);
            app.sinePosSpinner.Limits = [1 Inf];
            app.sinePosSpinner.RoundFractionalValues = 'on';
            app.sinePosSpinner.ValueChangedFcn = createCallbackFcn(app, @posChange, true);
            app.sinePosSpinner.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '点';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '个';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '采样频率';
            app.sineSampleFreq = uieditfield(app.UIFigure, 'numeric');
            app.sineSampleFreq.Limits = [1 Inf];
            app.sineSampleFreq.ValueDisplayFormat = '%.1f';
            app.sineSampleFreq.ValueChangedFcn = createCallbackFcn(app, @freqUpdateCallback, true);
            app.sineSampleFreq.Value = 2000;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '采样点数(与输入信号保持一致)';
            app.sampleNum = uieditfield(app.UIFigure, 'numeric');
            app.sampleNum.Limits = [1 Inf];
            app.sampleNum.ValueDisplayFormat = '%.0f';
            app.sampleNum.ValueChangedFcn = createCallbackFcn(app, @sampleNumValueChanged, true);
            app.sampleNum.Value = 200;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '正弦信号数量';
            app.sineNum = uispinner(app.UIFigure);
            app.sineNum.Limits = [1 Inf];
            app.sineNum.ValueChangedFcn = createCallbackFcn(app, @sineNumChangeCallback, true);
            app.sineNum.Value = 1;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '第                                                                个正弦信号频率设定';
            app.sinePosFreq = uieditfield(app.UIFigure, 'numeric');
            app.sinePosFreq.Limits = [0 Inf];
            app.sinePosFreq.ValueChangedFcn = createCallbackFcn(app, @posFreqChange, true);
            app.sinePosFreq.Value = 1000;
            app.sineGenFig = uiaxes(app.UIFigure);
            title(app.sineGenFig, 'Title')
            xlabel(app.sineGenFig, 'X')
            ylabel(app.sineGenFig, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @sineApply, true);
            app.Button.Text = '应用';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '噪音类型选择';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'正弦信号', '白噪音'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @noiseGenSelect, true);
            app.ListBox.Value = '白噪音';
        end
    end

    methods (Access = public)

        % Construct app
        function app = noiseGenApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)starupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end